classdef calcium_judge_interface_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        Calciumsignaljudginginterfacever2Label  matlab.ui.control.Label
        NextbatchButton         matlab.ui.control.Button
        LoaddataButton          matlab.ui.control.Button
        Label                   matlab.ui.control.Label
        Label_2                 matlab.ui.control.Label
        Label_3                 matlab.ui.control.Label
        Label_4                 matlab.ui.control.Label
        Label_5                 matlab.ui.control.Label
        Label_6                 matlab.ui.control.Label
        Label_7                 matlab.ui.control.Label
        Label_8                 matlab.ui.control.Label
        Label_9                 matlab.ui.control.Label
        Label_10                matlab.ui.control.Label
        Label_11                matlab.ui.control.Label
        Label_12                matlab.ui.control.Label
        Label_13                matlab.ui.control.Label
        Label_14                matlab.ui.control.Label
        Label_15                matlab.ui.control.Label
        Label_16                matlab.ui.control.Label
        SelectfalsetracesLabel  matlab.ui.control.Label
        falseButton             matlab.ui.control.StateButton
        falseButton_2           matlab.ui.control.StateButton
        falseButton_3           matlab.ui.control.StateButton
        falseButton_4           matlab.ui.control.StateButton
        falseButton_5           matlab.ui.control.StateButton
        falseButton_6           matlab.ui.control.StateButton
        falseButton_7           matlab.ui.control.StateButton
        falseButton_8           matlab.ui.control.StateButton
        falseButton_9           matlab.ui.control.StateButton
        falseButton_10          matlab.ui.control.StateButton
        falseButton_11          matlab.ui.control.StateButton
        falseButton_12          matlab.ui.control.StateButton
        falseButton_13          matlab.ui.control.StateButton
        falseButton_14          matlab.ui.control.StateButton
        falseButton_15          matlab.ui.control.StateButton
        falseButton_16          matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        file
        path
        data_raw
        data_filtered
        global_ind
        total_data_num
        curr_plot_num
        
        lambda = 0.8
        
        % batch size
        batch_size = 16
        
    end
    
    properties (Access = public)
        label_matrix % output matrix
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: NextbatchButton
        function NextbatchButtonPushed(app, event)
            raw_data_sub = app.data_raw(app.global_ind + 1 : min(app.global_ind + app.batch_size,...
                app.total_data_num), :);    
            

            if ~isempty(raw_data_sub)
                app.curr_plot_num = size(raw_data_sub, 1);
                
                % normalization
                ts = zscore(raw_data_sub, 0, 2); % get z score, kind of normalization tool
                y_shift = 5;
                clip = true;
                sel_nixs = 1:size(ts,1);
               
                % plot a batch
                app.UIAxes.cla;
                hold(app.UIAxes, 'on');
                for n_ix = 1:numel(sel_nixs)
                    loop_ts = ts(sel_nixs(n_ix),:);
                    ax = gca();
                    ax.ColorOrderIndex = 1;
                    loop_ts = ts(sel_nixs(n_ix),:);
                    if clip
                        loop_ts(loop_ts > 3*y_shift) = y_shift;
                        loop_ts(loop_ts < -3*y_shift) = -y_shift;
                    end
                    t = (0:size(ts,2)-1);
                    plot(app.UIAxes, t, squeeze(loop_ts) + y_shift*(n_ix-1));

                    app.UIAxes.Title.String = sprintf('No. %d - %d image in %d', app.global_ind + 1, ...
                        app.global_ind + app.curr_plot_num, app.total_data_num);
                end
                hold(app.UIAxes, 'off');
                app.UIAxes.YLim=[-5, 80];
                app.UIAxes.YTick = ((1 : app.curr_plot_num) - 1) * y_shift;
                app.UIAxes.YTickLabel = {'1', '2','3','4', '5','6','7', '8','9','10', '11','12','13', '14','15','16'} ;
                app.global_ind = app.global_ind + app.curr_plot_num;
                
                % initialize all the color and text in the font
                app.falseButton.Value = 1;
                app.falseButton.BackgroundColor = [0, 1, 0];
                app.falseButton.Text = 'True';

                app.falseButton_2.Value = 1;
                app.falseButton_2.BackgroundColor = [0, 1, 0];
                app.falseButton_2.Text = 'True';
                
                app.falseButton_3.Value = 1;
                app.falseButton_3.BackgroundColor = [0, 1, 0];
                app.falseButton_3.Text = 'True';
                
                app.falseButton_4.Value = 1;
                app.falseButton_4.BackgroundColor = [0, 1, 0];
                app.falseButton_4.Text = 'True';
                
                app.falseButton_5.Value = 1;
                app.falseButton_5.BackgroundColor = [0, 1, 0];
                app.falseButton_5.Text = 'True';
                
                app.falseButton_6.Value = 1;
                app.falseButton_6.BackgroundColor = [0, 1, 0];
                app.falseButton_6.Text = 'True';
                
                app.falseButton_7.Value = 1;
                app.falseButton_7.BackgroundColor = [0, 1, 0];
                app.falseButton_7.Text = 'True';
                
                app.falseButton_8.Value = 1;
                app.falseButton_8.BackgroundColor = [0, 1, 0];
                app.falseButton_8.Text = 'True';
                
                app.falseButton_9.Value = 1;
                app.falseButton_9.BackgroundColor = [0, 1, 0];
                app.falseButton_9.Text = 'True';
                
                app.falseButton_10.Value = 1;
                app.falseButton_10.BackgroundColor = [0, 1, 0];
                app.falseButton_10.Text = 'True';
                
                app.falseButton_11.Value = 1;
                app.falseButton_11.BackgroundColor = [0, 1, 0];
                app.falseButton_11.Text = 'True';
                
                app.falseButton_12.Value = 1;
                app.falseButton_12.BackgroundColor = [0, 1, 0];
                app.falseButton_12.Text = 'True';
                
                app.falseButton_13.Value = 1;
                app.falseButton_13.BackgroundColor = [0, 1, 0];
                app.falseButton_13.Text = 'True';
                
                app.falseButton_14.Value = 1;
                app.falseButton_14.BackgroundColor = [0, 1, 0];
                app.falseButton_14.Text = 'True';
                
                app.falseButton_15.Value = 1;
                app.falseButton_15.BackgroundColor = [0, 1, 0];
                app.falseButton_15.Text = 'True';
                
                app.falseButton_16.Value = 1;
                app.falseButton_16.BackgroundColor = [0, 1, 0];
                app.falseButton_16.Text = 'True';
                
                % first record
                app.label_matrix(app.global_ind - 15 : app.global_ind) = 1;
                label_matrix = app.label_matrix;
                save(sprintf('%s\\labeled_by_program_v2_%s', app.path, app.file), 'label_matrix')     
            end                
        end

        % Button pushed function: LoaddataButton
        function LoaddataButtonPushed(app, event)
            [app.file, app.path] = uigetfile('*.mat');
            data = importdata(sprintf('%s\\%s', app.path, app.file));
            app.data_raw = data.neuron_trace_mat;
            app.total_data_num = size(app.data_raw, 1);
            app.global_ind = 0;
            app.label_matrix = 2 * ones(app.total_data_num, 1);

        end

        % Callback function
        function TrueButtonPushed(app, event)
            
        end

        % Callback function
        function FalseButtonPushed(app, event)
            
        end

        % Callback function
        function NotsureButtonPushed(app, event)
            
        end

        % Callback function
        function fitButtonPushed(app, event)
            
        end

        % Callback function
        function lambdaEditFieldValueChanged(app, event)
            app.lambda= app.lambdaEditField.Value;
            
        end

        % Callback function
        function CheckBox_16ValueChanged(app, event)
            
        end

        % Callback function
        function CheckBoxValueChanged(app, event)
 
        end

        % Callback function
        function CheckBox_2ValueChanged(app, event)
          
        end

        % Callback function
        function CheckBox_3ValueChanged(app, event)
       
        end

        % Callback function
        function CheckBox_4ValueChanged(app, event)
         
        end

        % Callback function
        function CheckBox_5ValueChanged(app, event)
      
        end

        % Callback function
        function CheckBox_6ValueChanged(app, event)
       
        end

        % Callback function
        function CheckBox_7ValueChanged(app, event)
       
        end

        % Callback function
        function CheckBox_8ValueChanged(app, event)
        
        end

        % Callback function
        function CheckBox_9ValueChanged(app, event)
      
        end

        % Callback function
        function CheckBox_10ValueChanged(app, event)
      
        end

        % Callback function
        function CheckBox_11ValueChanged(app, event)
      
        end

        % Callback function
        function CheckBox_12ValueChanged(app, event)
         
        end

        % Callback function
        function CheckBox_13ValueChanged(app, event)
         
        end

        % Callback function
        function CheckBox_14ValueChanged(app, event)
       
        end

        % Callback function
        function CheckBox_15ValueChanged(app, event)
     
        end

        % Callback function
        function SwitchValueChanged(app, event)
            
        end

        % Value changed function: falseButton
        function falseButtonValueChanged(app, event)
            value = app.falseButton.Value;
            % assign color
            if value 
                app.falseButton.BackgroundColor = [0, 1, 0];
                app.falseButton.Text = 'True';
            else
                app.falseButton.BackgroundColor = [1, 0, 0];
                app.falseButton.Text = 'Flase';
            end
            app.label_matrix(app.global_ind - 15) = value;
            label_matrix = app.label_matrix;
            save(sprintf('%s\\labeled_by_program_v2_%s', app.path, app.file), 'label_matrix')     
        end

        % Value changed function: falseButton_2
        function falseButton_2ValueChanged(app, event)
            value = app.falseButton_2.Value;
            if value 
                app.falseButton_2.BackgroundColor = [0, 1, 0];
                app.falseButton_2.Text = 'True';
            else
                app.falseButton_2.BackgroundColor = [1, 0, 0];
                app.falseButton_2.Text = 'Flase';
            end
            app.label_matrix(app.global_ind - 14) = value;
            label_matrix = app.label_matrix;
            save(sprintf('%s\\labeled_by_program_v2_%s', app.path, app.file), 'label_matrix')               
        end

        % Value changed function: falseButton_3
        function falseButton_3ValueChanged(app, event)
            value = app.falseButton_3.Value;
            if value 
                app.falseButton_3.BackgroundColor = [0, 1, 0];
                app.falseButton_3.Text = 'True';
            else
                app.falseButton_3.BackgroundColor = [1, 0, 0];
                app.falseButton_3.Text = 'Flase';
            end
            app.label_matrix(app.global_ind - 13) = value;
            label_matrix = app.label_matrix;
            save(sprintf('%s\\labeled_by_program_v2_%s', app.path, app.file), 'label_matrix')    
        end

        % Value changed function: falseButton_4
        function falseButton_4ValueChanged(app, event)
            value = app.falseButton_4.Value;
            if value 
                app.falseButton_4.BackgroundColor = [0, 1, 0];
                app.falseButton_4.Text = 'True';
            else
                app.falseButton_4.BackgroundColor = [1, 0, 0];
                app.falseButton_4.Text = 'Flase';
            end
            app.label_matrix(app.global_ind - 12) = value;
            label_matrix = app.label_matrix;
            save(sprintf('%s\\labeled_by_program_v2_%s', app.path, app.file), 'label_matrix')                
        end

        % Value changed function: falseButton_5
        function falseButton_5ValueChanged(app, event)
            value = app.falseButton_5.Value;
            if value 
                app.falseButton_5.BackgroundColor = [0, 1, 0];
                app.falseButton_5.Text = 'True';
            else
                app.falseButton_5.BackgroundColor = [1, 0, 0];
                app.falseButton_5.Text = 'Flase';
            end
            app.label_matrix(app.global_ind - 11) = value;
            label_matrix = app.label_matrix;
            save(sprintf('%s\\labeled_by_program_v2_%s', app.path, app.file), 'label_matrix')                  
        end

        % Value changed function: falseButton_6
        function falseButton_6ValueChanged(app, event)
            value = app.falseButton_6.Value;
            if value 
                app.falseButton_6.BackgroundColor = [0, 1, 0];
                app.falseButton_6.Text = 'True';
            else
                app.falseButton_6.BackgroundColor = [1, 0, 0];
                app.falseButton_6.Text = 'Flase';
            end
            app.label_matrix(app.global_ind - 10) = value;
            label_matrix = app.label_matrix;
            save(sprintf('%s\\labeled_by_program_v2_%s', app.path, app.file), 'label_matrix')                  
        end

        % Value changed function: falseButton_7
        function falseButton_7ValueChanged(app, event)
            value = app.falseButton_7.Value;
            if value 
                app.falseButton_7.BackgroundColor = [0, 1, 0];
                app.falseButton_7.Text = 'True';
            else
                app.falseButton_7.BackgroundColor = [1, 0, 0];
                app.falseButton_7.Text = 'Flase';
            end
            app.label_matrix(app.global_ind - 9) = value;
            label_matrix = app.label_matrix;
            save(sprintf('%s\\labeled_by_program_v2_%s', app.path, app.file), 'label_matrix')                  
        end

        % Value changed function: falseButton_8
        function falseButton_8ValueChanged(app, event)
            value = app.falseButton_8.Value;
            if value 
                app.falseButton_8.BackgroundColor = [0, 1, 0];
                app.falseButton_8.Text = 'True';
            else
                app.falseButton_8.BackgroundColor = [1, 0, 0];
                app.falseButton_8.Text = 'Flase';
            end
            app.label_matrix(app.global_ind - 8) = value;
            label_matrix = app.label_matrix;
            save(sprintf('%s\\labeled_by_program_v2_%s', app.path, app.file), 'label_matrix')                  
        end

        % Value changed function: falseButton_9
        function falseButton_9ValueChanged(app, event)
            value = app.falseButton_9.Value;
            
            if value 
                app.falseButton_9.BackgroundColor = [0, 1, 0];
                app.falseButton_9.Text = 'True';
            else
                app.falseButton_9.BackgroundColor = [1, 0, 0];
                app.falseButton_9.Text = 'Flase';
            end
            app.label_matrix(app.global_ind - 7) = value;
            label_matrix = app.label_matrix;
            save(sprintf('%s\\labeled_by_program_v2_%s', app.path, app.file), 'label_matrix')                 
        end

        % Value changed function: falseButton_10
        function falseButton_10ValueChanged(app, event)
            value = app.falseButton_10.Value;
            
            if value 
                app.falseButton_10.BackgroundColor = [0, 1, 0];
                app.falseButton_10.Text = 'True';
            else
                app.falseButton_10.BackgroundColor = [1, 0, 0];
                app.falseButton_10.Text = 'Flase';
            end
            app.label_matrix(app.global_ind - 6) = value;
            label_matrix = app.label_matrix;
            save(sprintf('%s\\labeled_by_program_v2_%s', app.path, app.file), 'label_matrix')                    
        end

        % Value changed function: falseButton_11
        function falseButton_11ValueChanged(app, event)
            value = app.falseButton_11.Value;

            
            if value 
                app.falseButton_11.BackgroundColor = [0, 1, 0];
                app.falseButton_11.Text = 'True';
            else
                app.falseButton_11.BackgroundColor = [1, 0, 0];
                app.falseButton_11.Text = 'Flase';
            end
            app.label_matrix(app.global_ind - 5) = value;
            label_matrix = app.label_matrix;
            save(sprintf('%s\\labeled_by_program_v2_%s', app.path, app.file), 'label_matrix')        
        end

        % Value changed function: falseButton_12
        function falseButton_12ValueChanged(app, event)
            value = app.falseButton_12.Value;

            
            if value 
                app.falseButton_12.BackgroundColor = [0, 1, 0];
                app.falseButton_12.Text = 'True';
            else
                app.falseButton_12.BackgroundColor = [1, 0, 0];
                app.falseButton_12.Text = 'Flase';
            end
            app.label_matrix(app.global_ind - 4) = value;
            label_matrix = app.label_matrix;
            save(sprintf('%s\\labeled_by_program_v2_%s', app.path, app.file), 'label_matrix')        
        end

        % Value changed function: falseButton_13
        function falseButton_13ValueChanged(app, event)
            value = app.falseButton_13.Value;
           
            if value 
                app.falseButton_13.BackgroundColor = [0, 1, 0];
                app.falseButton_13.Text = 'True';
            else
                app.falseButton_13.BackgroundColor = [1, 0, 0];
                app.falseButton_13.Text = 'Flase';
            end
            app.label_matrix(app.global_ind - 3) = value;
            label_matrix = app.label_matrix;
            save(sprintf('%s\\labeled_by_program_v2_%s', app.path, app.file), 'label_matrix')        
        end

        % Value changed function: falseButton_14
        function falseButton_14ValueChanged(app, event)
            value = app.falseButton_14.Value;

            if value 
                app.falseButton_14.BackgroundColor = [0, 1, 0];
                app.falseButton_14.Text = 'True';
            else
                app.falseButton_14.BackgroundColor = [1, 0, 0];
                app.falseButton_14.Text = 'Flase';
            end
            app.label_matrix(app.global_ind - 2) = value;
            label_matrix = app.label_matrix;
            save(sprintf('%s\\labeled_by_program_v2_%s', app.path, app.file), 'label_matrix')   
        end

        % Value changed function: falseButton_15
        function falseButton_15ValueChanged(app, event)
            value = app.falseButton_15.Value;
            
            if value 
                app.falseButton_15.BackgroundColor = [0, 1, 0];
                app.falseButton_15.Text = 'True';
            else
                app.falseButton_15.BackgroundColor = [1, 0, 0];
                app.falseButton_15.Text = 'Flase';
            end
            app.label_matrix(app.global_ind - 1) = value;
            label_matrix = app.label_matrix;
            save(sprintf('%s\\labeled_by_program_v2_%s', app.path, app.file), 'label_matrix')               
        end

        % Value changed function: falseButton_16
        function falseButton_16ValueChanged(app, event)
            value = app.falseButton_16.Value;
            
            if value 
                app.falseButton_16.BackgroundColor = [0, 1, 0];
                app.falseButton_16.Text = 'True';
            else
                app.falseButton_16.BackgroundColor = [1, 0, 0];
                app.falseButton_16.Text = 'Flase';
            end
            app.label_matrix(app.global_ind) = value;
            label_matrix = app.label_matrix;
            save(sprintf('%s\\labeled_by_program_v2_%s', app.path, app.file), 'label_matrix')               
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Calciumsignaljudginginterfacever2Label = uilabel(app.UIFigure);
            app.Calciumsignaljudginginterfacever2Label.Text = 'Calcium signal judging interface ver2';
            app.NextbatchButton = uibutton(app.UIFigure, 'push');
            app.NextbatchButton.ButtonPushedFcn = createCallbackFcn(app, @NextbatchButtonPushed, true);
            app.NextbatchButton.Text = 'Next batch';
            app.LoaddataButton = uibutton(app.UIFigure, 'push');
            app.LoaddataButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddataButtonPushed, true);
            app.LoaddataButton.Text = 'Load data';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '1';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '2';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '3';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '4';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '5';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '6';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '7';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '8';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '9';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '10';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '11';
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '12';
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = '13';
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Text = '14';
            app.Label_15 = uilabel(app.UIFigure);
            app.Label_15.Text = '15';
            app.Label_16 = uilabel(app.UIFigure);
            app.Label_16.Text = '16';
            app.SelectfalsetracesLabel = uilabel(app.UIFigure);
            app.SelectfalsetracesLabel.Text = 'Select false traces!';
            app.falseButton = uibutton(app.UIFigure, 'state');
            app.falseButton.ValueChangedFcn = createCallbackFcn(app, @falseButtonValueChanged, true);
            app.falseButton.Text = 'false';
            app.falseButton_2 = uibutton(app.UIFigure, 'state');
            app.falseButton_2.ValueChangedFcn = createCallbackFcn(app, @falseButton_2ValueChanged, true);
            app.falseButton_2.Text = 'false';
            app.falseButton_3 = uibutton(app.UIFigure, 'state');
            app.falseButton_3.ValueChangedFcn = createCallbackFcn(app, @falseButton_3ValueChanged, true);
            app.falseButton_3.Text = 'false';
            app.falseButton_4 = uibutton(app.UIFigure, 'state');
            app.falseButton_4.ValueChangedFcn = createCallbackFcn(app, @falseButton_4ValueChanged, true);
            app.falseButton_4.Text = 'false';
            app.falseButton_5 = uibutton(app.UIFigure, 'state');
            app.falseButton_5.ValueChangedFcn = createCallbackFcn(app, @falseButton_5ValueChanged, true);
            app.falseButton_5.Text = 'false';
            app.falseButton_6 = uibutton(app.UIFigure, 'state');
            app.falseButton_6.ValueChangedFcn = createCallbackFcn(app, @falseButton_6ValueChanged, true);
            app.falseButton_6.Text = 'false';
            app.falseButton_7 = uibutton(app.UIFigure, 'state');
            app.falseButton_7.ValueChangedFcn = createCallbackFcn(app, @falseButton_7ValueChanged, true);
            app.falseButton_7.Text = 'false';
            app.falseButton_8 = uibutton(app.UIFigure, 'state');
            app.falseButton_8.ValueChangedFcn = createCallbackFcn(app, @falseButton_8ValueChanged, true);
            app.falseButton_8.Text = 'false';
            app.falseButton_9 = uibutton(app.UIFigure, 'state');
            app.falseButton_9.ValueChangedFcn = createCallbackFcn(app, @falseButton_9ValueChanged, true);
            app.falseButton_9.Text = 'false';
            app.falseButton_10 = uibutton(app.UIFigure, 'state');
            app.falseButton_10.ValueChangedFcn = createCallbackFcn(app, @falseButton_10ValueChanged, true);
            app.falseButton_10.Text = 'false';
            app.falseButton_11 = uibutton(app.UIFigure, 'state');
            app.falseButton_11.ValueChangedFcn = createCallbackFcn(app, @falseButton_11ValueChanged, true);
            app.falseButton_11.Text = 'false';
            app.falseButton_12 = uibutton(app.UIFigure, 'state');
            app.falseButton_12.ValueChangedFcn = createCallbackFcn(app, @falseButton_12ValueChanged, true);
            app.falseButton_12.Text = 'false';
            app.falseButton_13 = uibutton(app.UIFigure, 'state');
            app.falseButton_13.ValueChangedFcn = createCallbackFcn(app, @falseButton_13ValueChanged, true);
            app.falseButton_13.Text = 'false';
            app.falseButton_14 = uibutton(app.UIFigure, 'state');
            app.falseButton_14.ValueChangedFcn = createCallbackFcn(app, @falseButton_14ValueChanged, true);
            app.falseButton_14.Text = 'false';
            app.falseButton_15 = uibutton(app.UIFigure, 'state');
            app.falseButton_15.ValueChangedFcn = createCallbackFcn(app, @falseButton_15ValueChanged, true);
            app.falseButton_15.Text = 'false';
            app.falseButton_16 = uibutton(app.UIFigure, 'state');
            app.falseButton_16.ValueChangedFcn = createCallbackFcn(app, @falseButton_16ValueChanged, true);
            app.falseButton_16.Text = 'false';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = calcium_judge_interface_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end